classdef p_infinity_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        p_infinityTextArea       matlab.ui.control.TextArea
        p_infinityTextAreaLabel  matlab.ui.control.Label
        ProbabilitymatrixptobenormalizedTextArea  matlab.ui.control.TextArea
        ProbabilitymatrixpLabel  matlab.ui.control.Label
        RecalculateButton        matlab.ui.control.Button
        ExitButton               matlab.ui.control.Button
        Calculationofp_infinityfrompLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: p_infinityTextArea
        function p_infinityTextAreaValueChanged(app, event)
            value = app.p_infinityTextArea.Value;
            
        end

        % Button pushed function: RecalculateButton
        function RecalculateButtonPushed(app, event)

    % Combine all lines of the input into a single string
            userInput = strjoin(app.ProbabilitymatrixptobenormalizedTextArea.Value, ' '); 
    
    % Convert the input string into a numeric matrix
            P = str2num(userInput); 
    
    % Validate that P is not empty
            if isempty(P)
                app.p_infinityTextArea.Value = {'P is not a valid matrix.'; 'Please enter a valid matrix.'};
                return;
            end
    
    % Normalize the matrix P (row-wise)
            rowSums = sum(P, 2); % Calculate the sum of each row
            if any(rowSums == 0) % Check for rows with all zeros
                app.p_infinityTextArea.Value = {'P contains a row with all zeros.'; 'Cannot normalize such a matrix.'};
                return;
            end
            P = P ./ rowSums; % Divide each row by its sum to normalize
    
    % Calculate steady-state p_infinity
            [V, D] = eig(P'); % Compute the eigenvectors and eigenvalues of P'
    % Find the eigenvector associated with the eigenvalue 1
            [~, idx] = min(abs(diag(D) - 1)); 
            p_infinity = V(:, idx); 
            p_infinity = p_infinity / sum(p_infinity); % Normalize the eigenvector
    
    % Display the result
            app.p_infinityTextArea.Value = {sprintf('p_infinity = [%s]', num2str(p_infinity', ' %.5f'))};

        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Calculationofp_infinityfrompLabel = uilabel(app.UIFigure);
            app.Calculationofp_infinityfrompLabel.Text = 'Calculation of p_infinity from p';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.RecalculateButton = uibutton(app.UIFigure, 'push');
            app.RecalculateButton.ButtonPushedFcn = createCallbackFcn(app, @RecalculateButtonPushed, true);
            app.RecalculateButton.Text = 'Recalculate';
            app.ProbabilitymatrixpLabel = uilabel(app.UIFigure);
            app.ProbabilitymatrixpLabel.Text = {'Probability matrix p'; '(to be normalized)'};
            app.ProbabilitymatrixptobenormalizedTextArea = uitextarea(app.UIFigure);
            app.ProbabilitymatrixptobenormalizedTextArea.Value = {'[1 3;2 8]'};
            app.p_infinityTextAreaLabel = uilabel(app.UIFigure);
            app.p_infinityTextAreaLabel.Text = 'p_infinity';
            app.p_infinityTextArea = uitextarea(app.UIFigure);
            app.p_infinityTextArea.ValueChangedFcn = createCallbackFcn(app, @p_infinityTextAreaValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = p_infinity_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end