classdef assig2b < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        CalculateButton              matlab.ui.control.Button
        DifferentialentropyTextArea  matlab.ui.control.TextArea
        DifferentialentropyTextAreaLabel  matlab.ui.control.Label
        RangeofxTextArea             matlab.ui.control.TextArea
        RangeofxTextAreaLabel        matlab.ui.control.Label
        f_XxproportionaltoTextArea   matlab.ui.control.TextArea
        f_XxproportionaltoTextAreaLabel  matlab.ui.control.Label
        ExitButton                   matlab.ui.control.Button
        DifferentialEntropyCalculatorLabel  matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            try
                % Parse inputs
                funcStr = char(app.f_XxproportionaltoTextArea.Value);
                rangeStr = char(app.RangeofxTextArea.Value);
                pdfFunc = str2func(['@(x) ' funcStr]);
                range = str2num(rangeStr);
                if numel(range) ~= 2
                    uialert(app.UIFigure, 'Please enter a valid range (e.g., 0,10).', 'Invalid Input');
                    return;
                end
            
                % Normalize the PDF
                Z = integral(pdfFunc, range(1), range(2)); % Compute normalization constant
                if Z == 0
                    uialert(app.UIFigure, 'Normalization constant is zero. Check your function or range.', 'Error');
                    return;
                end
                normalizedPDF = @(x) pdfFunc(x) / Z; % Define normalized PDF
            
                % Compute differential entropy
                entropyFunc = @(x) -normalizedPDF(x) .* log2(normalizedPDF(x) + eps); % Add eps for stability
                differentialEntropy = integral(entropyFunc, range(1), range(2));
            
                % Display results
                app.DifferentialentropyTextArea.Value = sprintf('%.6f', differentialEntropy);
            
                % Plot the normalized PDF
                x = linspace(range(1), range(2), 1000);
                y = normalizedPDF(x);
                plot(app.UIAxes, x, y);
                xlabel(app.UIAxes, 'x');
                ylabel(app.UIAxes, 'f_X(x)');
                title(app.UIAxes, 'Normalized Probability Density Function');
            catch ME
                uialert(app.UIFigure, ['An error occurred: ' ME.message], 'Error');
            end


        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, ' ')
            xlabel(app.UIAxes, 'x')
            ylabel(app.UIAxes, 'fx(n)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTickLabel = {'0'; '2'; '4'; '6'; '8'; '10'};
            app.DifferentialEntropyCalculatorLabel = uilabel(app.UIFigure);
            app.DifferentialEntropyCalculatorLabel.Text = 'Differential Entropy Calculator';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.f_XxproportionaltoTextAreaLabel = uilabel(app.UIFigure);
            app.f_XxproportionaltoTextAreaLabel.Text = 'f_X(x) proportional to :';
            app.f_XxproportionaltoTextArea = uitextarea(app.UIFigure);
            app.f_XxproportionaltoTextArea.Value = {'1./(x.^2+1)'};
            app.RangeofxTextAreaLabel = uilabel(app.UIFigure);
            app.RangeofxTextAreaLabel.Text = 'Range of x';
            app.RangeofxTextArea = uitextarea(app.UIFigure);
            app.RangeofxTextArea.Value = {'0, 10'};
            app.DifferentialentropyTextAreaLabel = uilabel(app.UIFigure);
            app.DifferentialentropyTextAreaLabel.Text = 'Differential entropy =';
            app.DifferentialentropyTextArea = uitextarea(app.UIFigure);
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = assig2b

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end